classdef CV2021Challenge_TabManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ImagePreprocessingTab          matlab.ui.container.Tab
        ChooseReferenceImageButton     matlab.ui.control.Button
        StartImage                     matlab.ui.control.Image
        PleaseuploadadatasettostarttheimageanalysisLabel  matlab.ui.control.Label
        FinishedButton                 matlab.ui.control.Button
        ManualCalibrationButton        matlab.ui.control.Button
        nextimageButton                matlab.ui.control.Button
        previousimageButton            matlab.ui.control.Button
        SliderImagePreprocessing       matlab.ui.control.Slider
        UploadDatasetButton            matlab.ui.control.Button
        UIAxesImagePreprocessing       matlab.ui.control.UIAxes
        UIAxesImagePreprocessingSmall  matlab.ui.control.UIAxes
        DifferenceMapTab               matlab.ui.container.Tab
        ShowDiffenceswithLabel         matlab.ui.control.Label
        BoundingBlockButton            matlab.ui.control.Button
        ChangeMapButton                matlab.ui.control.Button
        ChangeSizeLabel                matlab.ui.control.Label
        DetectingStrengthLabel         matlab.ui.control.Label
        StrongLabel                    matlab.ui.control.Label
        WeakLabel                      matlab.ui.control.Label
        SliderStrenght                 matlab.ui.control.Slider
        ChangeStatisticsforDatasetLabel  matlab.ui.control.Label
        Image2Label                    matlab.ui.control.Label
        Image1Label                    matlab.ui.control.Label
        BigChangesLabel                matlab.ui.control.Label
        SmallChangesLabel              matlab.ui.control.Label
        SliderChanges                  matlab.ui.control.Slider
        SliderAfter                    matlab.ui.control.Slider
        SliderBefore                   matlab.ui.control.Slider
        UIAxesDifferencesMap_2         matlab.ui.control.UIAxes
        UIAxesDifferenceMapGraph       matlab.ui.control.UIAxes
        UIAxesDifferenceMapAfter       matlab.ui.control.UIAxes
        UIAxesDifferenceMapBefore      matlab.ui.control.UIAxes
        UIAxesDifferencesMap           matlab.ui.control.UIAxes
        TimelapseTab                   matlab.ui.container.Tab
        SliderTimelapse                matlab.ui.control.Slider
        UIAxesStartImageTimelapse      matlab.ui.control.UIAxes
        EnvironmentChangesTab          matlab.ui.container.Tab
        PercentageOfDiff               matlab.ui.control.EditField
        PercentageDiff                 matlab.ui.control.NumericEditField
        PercentageOf2                  matlab.ui.control.EditField
        Percentage2                    matlab.ui.control.NumericEditField
        PercentageOf1                  matlab.ui.control.EditField
        Percentage1                    matlab.ui.control.NumericEditField
        ObjectTypeLabel                matlab.ui.control.Label
        BuildingsSnowButton            matlab.ui.control.Button
        SandLandButton                 matlab.ui.control.Button
        WaterButton                    matlab.ui.control.Button
        PlantsForestButton             matlab.ui.control.Button
        SliderImage2                   matlab.ui.control.Slider
        SliderImage1                   matlab.ui.control.Slider
        ChangeStatisticsforDatasetLabel2  matlab.ui.control.Label
        UIAxesImage2                   matlab.ui.control.UIAxes
        UIAxesImage1                   matlab.ui.control.UIAxes
        UIAxesEnvironmentChangesGraph  matlab.ui.control.UIAxes
        EnvironmentClassificationTab   matlab.ui.container.Tab
        SliderEnvironmentClassification  matlab.ui.control.Slider
        NumberofClustersSlider         matlab.ui.control.Slider
        NumberofClustersSliderLabel    matlab.ui.control.Label
        ClassStatisticsLabel           matlab.ui.control.Label
        UIAxesEnvironmentClassificationGraph  matlab.ui.control.UIAxes
        UIAxesClassmap                 matlab.ui.control.UIAxes
        UIAxesOriginal                 matlab.ui.control.UIAxes
        AutomaticAnalyserofSatellitePicturesLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        imagefiles;                         % Variable to store the original image folder
        files = {};                         % Variables for uploaded images
        original_dataset;                   % Original dataset of images
        
        total_pictures;                     % Number of total pictures
        file_selector_preprocessing_value;  % Variable to store the current image being shown on the Image Preprocessing tab
    end
    
    methods (Access = public)
        
        function [changeIndex, changeMap] = images2_changemap(app ,I_ref, I_aligned, threshold, pixelgroup)
            %
                %% [changeIndex, changeMap, I_aligned] = align_2images_changemap(I_ref, I, threshold, pixelgroup)
                % I_ref: Reference image ;
                % I_aligned: aligned image. 
                % threshold: ssim value, range [0,1].  Where 1 means the same and 0 means totally different.
                    % pixelgroup: The smallest size is recognized as a different set of pixels;
                %
                % changeIndex: Coordinate [x, y] of different points, size();
                % changeMap: Binary map of different areas of the image;


            ssimMap = ssim_map(I_ref,I_aligned, threshold); % you can also set a threshold
            changeMap = bwareaopen(ssimMap,pixelgroup); % Remove small sets of pixels

            % Find the coordinates of the different-points
            [row, col]= find(ssimMap~=0); 
            changeIndex = [col, row];
            %
            
        end
        
        function out = color_detection(app,I)
            sThresh = [0.15 1];
            hThresh = [0.2 1];
        
            % farberkennung
            I_hsv = rgb2hsv(I);
            I_farbwert = round(I_hsv(:,:,1)*360);
            I_s = I_hsv(:,:,2);
            I_h = I_hsv(:,:,3);
            % Erzeugen der Treshold matrix
            I_thresh = (I_s>=sThresh(1))&(I_s<=sThresh(2))&(I_h>=hThresh(1))&(I_h<=hThresh(2)); 
         
                
            % Filtern  nach Farbe
            schnee_buildings = (I_s<sThresh(1))&(I_h>=hThresh(1));
            vegetation = ((I_farbwert>90)&(I_farbwert<=150))&I_thresh;
            wasser = ((I_farbwert>150)&(I_farbwert<=270))&I_thresh;
            sand_erde= ((I_farbwert>5)&(I_farbwert<=90))&I_thresh;

            out(:,:,1)= schnee_buildings;
            out(:,:,2) = vegetation;
            out(:,:,3) = wasser;
            out(:,:,4)=sand_erde;   

        end
        
        function [percentage,I_mask] = ImageEnvironmentChanges(app,I,n)
            co_o=app.color_detection(I);


            % ignoriert pixel Gruppen die kleiner als 50 sind
            smallestAcceptableArea=50;
            newObjectsMask = uint8(bwareaopen(co_o(:,:,n), smallestAcceptableArea));
            
            %berechnen der prozentzahl von z.B wasser
            percentage = nnz(newObjectsMask)/ numel(newObjectsMask);
            
            
            % erste vilsialisierung
            
            newObjectsMask(:,end)=0;
            newObjectsMask(:,1)=0;
            newObjectsMask(end,:)=0;
            newObjectsMask(1,:)=0;
            
            %Mask visualisation
            mask=newObjectsMask;
            I_mask(:,:,1)=I(:,:,1).*uint8(not(logical(mask)));
            I_mask(:,:,2)=I(:,:,2).*uint8(((mask*254)+1));
            I_mask(:,:,3)=I(:,:,3).*uint8(not(logical(mask)));

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Make the functions visible to matlab
            path(path,'alignment')
            path(path,'change_map')           
            path(path,'color detection')
            
            % Hide UI before there are any pictures in Image Preprocessing tab
            app.StartImage.Visible='on';
            app.SliderImagePreprocessing.Visible='off';
            app.UIAxesImagePreprocessingSmall.Visible='off';
            app.UIAxesImagePreprocessing.Visible='off';
            app.previousimageButton.Visible='off';
            app.nextimageButton.Visible='off';
            app.ManualCalibrationButton.Visible='off';
            app.FinishedButton.Visible='off';
            
            % Hide UI before there are any pictures in Differences Map tab
            app.UIAxesDifferenceMapBefore.Visible = 'off';
            app.UIAxesDifferenceMapAfter.Visible = 'off';
            app.UIAxesDifferencesMap.Colormap = gray(256);
            axis(app.UIAxesDifferencesMap, 'image');
            app.SliderAfter.Visible = 'off';
            app.SliderBefore.Visible = 'off';
            app.Image2Label.Visible = 'off';
            app.Image1Label.Visible = 'off';
            app.SliderChanges.Visible='off';
            app.UIAxesDifferenceMapGraph.Visible='off';
            app.BigChangesLabel.Visible='off';
            app.ChangeStatisticsforDatasetLabel.Visible='off';
            app.SmallChangesLabel.Visible='off';
            
            app.UIAxesDifferencesMap.Visible='off';
            app.UIAxesDifferencesMap_2.Visible='off';
            app.UIAxesDifferenceMapGraph.Visible='off';
            % Slider settings in Differences Map tab
            app.SliderBefore.Limits = [0 1];
            app.SliderAfter.Limits = [0 1];
            app.SliderBefore.MajorTicks = [0 1];
            app.SliderAfter.MajorTicks = [0 1];
            app.SliderBefore.MinorTicksMode = 'manual';
            app.SliderAfter.MinorTicksMode = 'manual';
            
            % Hide UI before there are any pictures in Timelapse tab
            %app.SelectImageTimelapse.Visible = 'off';
            app.SliderTimelapse.Visible = 'off';
            app.UIAxesStartImageTimelapse.Visible = 'off';
            
            % Slider settings in Timelapse tab
            app.SliderTimelapse.Limits = [0 1];
            app.SliderTimelapse.MajorTicks = [0 1];
            app.SliderTimelapse.MinorTicksMode = 'manual';
            
            % Hide UI before there are any pictures in Environment Changes tab
            app.SliderImage1.Visible = 'off';
            app.SliderImage2.Visible = 'off';
            app.UIAxesImage1.Visible = 'off';
            app.UIAxesImage2.Visible = 'off';
            
            % Slider settings in Environment Changes tab
            app.SliderImage1.Limits = [0 1];
            app.SliderImage2.Limits = [0 1];
            app.SliderImage1.MajorTicks = [0 1];
            app.SliderImage2.MajorTicks = [0 1];
            app.SliderImage1.MinorTicksMode = 'manual';
            app.SliderImage2.MinorTicksMode = 'manual';
            
            % Hide UI before there are any pictures in Environment Classification tab
            app.SliderEnvironmentClassification.Visible = 'off';
            app.UIAxesClassmap.Visible = 'off';
            app.UIAxesOriginal.Visible = 'off';
            
            % Slider settings in Environment Classification tab
            app.SliderEnvironmentClassification.Limits = [0 1];
            app.SliderEnvironmentClassification.MajorTicks = [0 1];
            app.SliderEnvironmentClassification.MinorTicksMode = 'manual';
        end

        % Button pushed function: UploadDatasetButton
        function UploadDatasetButtonPushed(app, event)
            % Let the user select folder
            dirname = uigetdir;
            
            % Read the images from that folder
            app.imagefiles = [dir(fullfile(dirname, '*.jpg')), dir(fullfile(dirname, '*.png'))];
            nfiles = length(app.imagefiles);
            
            % Add them to our list
            for i = 1:nfiles
                currentfilename = fullfile(dirname, app.imagefiles(i).name);
                currentimage = imread(currentfilename);
                images{i} = currentimage;
            end
            app.files = images;
            app.original_dataset = app.files;
            
            % Create limits for the slider
            app.SliderImagePreprocessing.Limits = [1, numel(app.files)];
            app.SliderBefore.Limits = [1, numel(app.files)];
            app.SliderAfter.Limits = [1, numel(app.files)];
            app.SliderTimelapse.Limits = [1, numel(app.files)];
            app.SliderImage1.Limits = [1, numel(app.files)];
            app.SliderImage2.Limits = [1, numel(app.files)];
            app.SliderEnvironmentClassification.Limits = [1, numel(app.files)];
            
            % Create a list of names for the tick marks
            tickmarks = [];
            matches = [".jpg", ".png"];
            for i = 1:nfiles
                tickmarks = [tickmarks, erase(string(app.imagefiles(i).name), matches)];
            end
            
            % Create tick marks
            major_ticks = [];
            for i = 1:numel(app.files)
                major_ticks = [major_ticks i];
            end
            app.SliderImagePreprocessing.MajorTicks = major_ticks;
            app.SliderBefore.MajorTicks = major_ticks;
            app.SliderAfter.MajorTicks = major_ticks;
            app.SliderTimelapse.MajorTicks = major_ticks;
            app.SliderImage1.MajorTicks = major_ticks;
            app.SliderImage2.MajorTicks = major_ticks;
            app.SliderEnvironmentClassification.MajorTicks = major_ticks;
            app.UIAxesDifferenceMapGraph.XTick = major_ticks;
            app.UIAxesEnvironmentChangesGraph.XTick = major_ticks;
            
            app.SliderImagePreprocessing.MajorTickLabels = tickmarks;
            app.SliderBefore.MajorTickLabels = tickmarks;
            app.SliderAfter.MajorTickLabels = tickmarks;
            app.SliderTimelapse.MajorTickLabels = tickmarks;
            app.SliderImage1.MajorTickLabels = tickmarks;
            app.SliderImage2.MajorTickLabels = tickmarks;
            app.SliderEnvironmentClassification.MajorTickLabels = tickmarks;
            
            % Show options again on Differences Map tab
            app.SliderAfter.Visible = 'on';
            app.SliderBefore.Visible = 'on';
            app.Image2Label.Visible = 'on';
            app.Image1Label.Visible = 'on';
            app.UIAxesDifferenceMapBefore.Visible = 'on';
            app.UIAxesDifferenceMapAfter.Visible = 'on';
            %app.ShowdifferencespleasechooseamethodButton.Visible = 'on';
            app.SliderChanges.Visible='on';
            app.UIAxesDifferenceMapGraph.Visible='on';
            app.BigChangesLabel.Visible='on';
            app.ChangeStatisticsforDatasetLabel.Visible='on';
            app.SmallChangesLabel.Visible='on';
            app.PleaseuploadadatasettostarttheimageanalysisLabel.Visible='off';
            app.StartImage.Visible='off';
            
            name_set = replace(tickmarks, '_', '/');
            app.UIAxesDifferenceMapGraph.XTickLabel = name_set;    % Set names for graph
            
            
            % Show options again on Image Preprocessing tab
            app.SliderImagePreprocessing.Visible='on';
            app.previousimageButton.Visible='off';
            app.nextimageButton.Visible='off';
            app.UIAxesImagePreprocessingSmall.Visible='on';
            app.UIAxesImagePreprocessing.Visible='off';
            app.ManualCalibrationButton.Visible='on';
            app.FinishedButton.Visible='on';
            app.ChooseReferenceImageButton.Visible='on';
            
            % Show options again on Timelapse tab
            %app.SelectImageTimelapse.Visible = 'on';
            app.SliderTimelapse.Visible = 'on';
            app.UIAxesStartImageTimelapse.Visible = 'on';
            
            % Show options again on Environment Changes tab
            app.SliderImage1.Visible = 'on';
            app.SliderImage2.Visible = 'on';
            app.UIAxesImage1.Visible = 'on';
            app.UIAxesImage2.Visible = 'on';
            
            % Show options again on Environment Classification tab
            app.SliderEnvironmentClassification.Visible = 'on';
            app.UIAxesClassmap.Visible = 'on';
            app.UIAxesOriginal.Visible = 'on';
            
            %Xtick vor Environment Changes
            name_set = replace(tickmarks, '_', '/');
            app.UIAxesEnvironmentChangesGraph.XTickLabel = name_set;
            
            % Show first image
            imshow(app.original_dataset{1}, 'parent', app.UIAxesImagePreprocessingSmall);
        end

        % Value changed function: SliderImagePreprocessing
        function SliderImagePreprocessingValueChanged(app, event)
            value = app.SliderImagePreprocessing.Value;
            app.SliderImagePreprocessing.Value = round(value);
            
            % Show the image
            imshow(app.original_dataset{app.SliderImagePreprocessing.Value}, 'parent', app.UIAxesImagePreprocessingSmall);
        end

        % Button pushed function: ChooseReferenceImageButton
        function ChooseReferenceImageButtonPushed(app, event)
            % Preprocess batch of images and save them in their own folder
            
            % Show options again on Image Preprocessing tab
            app.UIAxesImagePreprocessing.Visible ='on';
            app.previousimageButton.Visible ='on';
            app.nextimageButton.Visible ='on';
            
            % Select index from slider
            ref_index = uint8(app.SliderImagePreprocessing.Value);
            
            % choose the reference image
            I_ref = app.original_dataset{ref_index};
            I_ref = google_delete(I_ref);
            
            % Create folder to save aligned dataset
            folder = './Datasets_aligned';
            if exist(folder,'dir')
                rmdir(folder, 's');
                mkdir(folder);
            else
                mkdir(folder);
            end
            
            index = fullfile(pwd, 'Datasets_aligned', app.imagefiles(ref_index).name);
            imwrite(I_ref, index);

            for  i = 1:numel(app.original_dataset)
                if i == ref_index
                    continue;
                end
                
                I = app.original_dataset{i};
                I = google_delete(I);
                I_aligned = alignment(I_ref, I);
                
                index_i = fullfile(pwd ,'Datasets_aligned',app.imagefiles(i).name);
                imwrite(I_aligned, index_i); 
            end
            
            % After being preprocessed, they need to be shown in order to
            % see if any need to be manually processed
            
            % Read images from processed folder
            imgPath = fullfile(pwd, 'Datasets_aligned'); 
            imgDir  = dir(fullfile(imgPath, '*.jpg'));

            % Read all the images in the folder
            for i = 1:length(imgDir)
                img = imread(fullfile(imgPath,imgDir(i).name));
                app.files{i}=img;
            end
            
            % Print the first one and prepare the selector for manual
            % calibration
            app.total_pictures = length(imgDir);
            app.file_selector_preprocessing_value = 1;
            imshow(app.files{app.file_selector_preprocessing_value}, 'parent', app.UIAxesImagePreprocessing);
            
            % Show buttons
            app.FinishedButton.Visible = 'on';
            app.ManualCalibrationButton.Visible = 'on';
        end

        % Button pushed function: nextimageButton
        function nextimageButtonPushed(app, event)
            app.file_selector_preprocessing_value = app.file_selector_preprocessing_value + 1;
            
            if app.file_selector_preprocessing_value > app.total_pictures
                app.file_selector_preprocessing_value = 1;
            end
            
            imshow(app.files{app.file_selector_preprocessing_value}, 'parent', app.UIAxesImagePreprocessing);
        end

        % Button pushed function: previousimageButton
        function previousimageButtonPushed(app, event)
            app.file_selector_preprocessing_value = app.file_selector_preprocessing_value - 1;
            
            if app.file_selector_preprocessing_value <= 0
                app.file_selector_preprocessing_value = app.total_pictures;
            end
            
            imshow(app.files{app.file_selector_preprocessing_value}, 'parent', app.UIAxesImagePreprocessing);
        end

        % Button pushed function: ManualCalibrationButton
        function ManualCalibrationButtonPushed(app, event)
            % Manually calibrate this image
            
            % Select images% Select index from slider
            ref_index = uint8(app.SliderImagePreprocessing.Value);
            I_ref = app.original_dataset{ref_index};
            I = app.original_dataset{app.file_selector_preprocessing_value};

            % Delete google Logo
            I_ref = google_delete(I_ref);
            I = google_delete(I);

            % Select the matched points manually
            [mp,fp] = cpselect(I,I_ref,'Wait',true);
            t = fitgeotrans(mp,fp,'projective');
            %[t,inlierIdx] = estimateGeometricTransform2D(fp,mp,'similarity');

            % Crop image
            Rfixed = imref2d(size(I_ref));
            registered = imwarp(I,t,'OutputView',Rfixed);

            % Visualization
            index_i = fullfile(pwd ,'Datasets_aligned', app.imagefiles(app.file_selector_preprocessing_value).name);
            imwrite(registered, index_i);
            
            % Reload images
            imgPath = fullfile(pwd, 'Datasets_aligned'); 
            imgDir  = dir(fullfile(imgPath, '*.jpg'));
            for i = 1:length(imgDir)
                img = imread(fullfile(imgPath,imgDir(i).name));
                app.files{i}=img;
            end
            
            imshow(app.files{app.file_selector_preprocessing_value}, 'parent', app.UIAxesImagePreprocessing);
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            % Load images for timelapse
            imshow(app.files{1}, 'parent', app.UIAxesStartImageTimelapse);
        end

        % Value changed function: SliderBefore
        function SliderBeforeValueChanged(app, event)
            value = app.SliderBefore.Value;
            app.SliderBefore.Value = round(value);
            
            % Show the image
            imshow(app.files{app.SliderBefore.Value}, 'parent', app.UIAxesDifferenceMapBefore);
        end

        % Value changed function: SliderAfter
        function SliderAfterValueChanged(app, event)
            value = app.SliderAfter.Value;
            app.SliderAfter.Value = round(value);
            
            % Show the image
            imshow(app.files{app.SliderAfter.Value}, 'parent', app.UIAxesDifferenceMapAfter);
        end

        % Callback function
        function ShowdifferencespleasechooseamethodButtonPushed(app, event)
            % PLEASE MODIFY THIS FUNCTION AFTER ADDING FUNCTIONALITY 
            % THIS IS JUST A MOCKUP
            %app.UIAxesDifferencesMap.Visible = 'off';
            %imshow(app.files{1}, 'parent', app.UIAxesDifferencesMap);
        end

        % Value changed function: SliderTimelapse
        function SliderTimelapseValueChanged(app, event)
            value = app.SliderTimelapse.Value;
            app.SliderTimelapse.Value = round(value);
            
            % Show the image
            imshow(app.files{app.SliderTimelapse.Value}, 'parent', app.UIAxesStartImageTimelapse);
        end

        % Value changed function: SliderImage1
        function SliderImage1ValueChanged(app, event)
            value = app.SliderImage1.Value;
            app.SliderImage1.Value = round(value);
            
            % Show the image
            %imshow(app.files{app.SliderImage1.Value}, 'parent', app.UIAxesImage1);
        end

        % Value changed function: SliderImage2
        function SliderImage2ValueChanged(app, event)
            value = app.SliderImage2.Value;
            app.SliderImage2.Value = round(value);
            
            % Show the image
            %imshow(app.files{app.SliderImage2.Value}, 'parent', app.UIAxesImage2);
        end

        % Value changed function: SliderEnvironmentClassification
        function SliderEnvironmentClassificationValueChanged(app, event)
            % The whole script is run when the slider is moved
            
            % Select original image
            value = app.SliderEnvironmentClassification.Value;
            app.SliderEnvironmentClassification.Value = round(value);
            
            % Show the original image
            imshow(app.original_dataset{app.SliderEnvironmentClassification.Value}, 'parent', app.UIAxesOriginal);
            
            I = app.original_dataset{app.SliderEnvironmentClassification.Value};
            I_g = im2gray(I);

            % Anzahl der cluster auswählen
            anzahl_cluster = app.NumberofClustersSlider.Value;

            %kmeans anwenden. wobei L die angehörigkeit der pixel zu einem kluster
            %anzeigt und Centers die Mittepunkte der cluster sind.
            [L,Centers] = imsegkmeans(I,anzahl_cluster);
            B = labeloverlay(I,L);
            
            % Show the classified image
            imshow(B, 'parent', app.UIAxesClassmap);

            % Kuchen diagramm
            switch anzahl_cluster
                case 2
                    %labels festlegen
                    labels = {'Cluster 1','Cluster 2'};
                    %prozente berechnen
                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    X=[k_2 k_1];

                case 3
                    labels = {'Cluster 1','Cluster 2','Cluster 3'};

                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    k_3=find(L==3);
                    k_3= numel(k_3)/numel(I_g);

                    X=[k_2 k_3 k_1];

                case 4
                    labels = {'Cluster 1','Cluster 2','Cluster 3','Cluster 4'};

                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    k_3=find(L==3);
                    k_3= numel(k_3)/numel(I_g);

                    k_4=find(L==4);
                    k_4= numel(k_4)/numel(I_g);

                    X=[k_4 k_2 k_1 k_3];

                case 5
                    labels = {'Cluster 1','Cluster 2','Cluster 3','Cluster 4','Cluster 5'};

                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    k_3=find(L==3);
                    k_3= numel(k_3)/numel(I_g);

                    k_4=find(L==4);
                    k_4= numel(k_4)/numel(I_g);

                    k_5=find(L==5);
                    k_5= numel(k_5)/numel(I_g);

                    X=[k_1 k_2 k_3 k_4 k_5];

                case 6
                    labels = {'Cluster 1','Cluster 2','Cluster 3','Cluster 4','Cluster 5','Cluster 6'};

                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    k_3=find(L==3);
                    k_3= numel(k_3)/numel(I_g);

                    k_4=find(L==4);
                    k_4= numel(k_4)/numel(I_g);

                    k_5=find(L==5);
                    k_5= numel(k_5)/numel(I_g);

                    k_6=find(L==6);
                    k_6= numel(k_6)/numel(I_g);

                    X=[k_1 k_2 k_3 k_4 k_5 k_6];
            end
            
            pie(app.UIAxesEnvironmentClassificationGraph, X);
            lgd = legend(app.UIAxesEnvironmentClassificationGraph, labels);
            lgd.Location = 'best';
        end

        % Value changed function: NumberofClustersSlider
        function NumberofClustersSliderValueChanged(app, event)
            value = app.NumberofClustersSlider.Value;
            app.NumberofClustersSlider.Value = round(value);
            
            % The whole script is run when the slider is moved
            
            % Select original image
            value = app.SliderEnvironmentClassification.Value;
            app.SliderEnvironmentClassification.Value = round(value);
            
            % Show the original image
            imshow(app.original_dataset{app.SliderEnvironmentClassification.Value}, 'parent', app.UIAxesOriginal);
            
            I = app.original_dataset{app.SliderEnvironmentClassification.Value};
            I_g = im2gray(I);

            % Anzahl der cluster auswählen
            anzahl_cluster = app.NumberofClustersSlider.Value;

            %kmeans anwenden. wobei L die angehörigkeit der pixel zu einem kluster
            %anzeigt und Centers die Mittepunkte der cluster sind.
            [L,Centers] = imsegkmeans(I,anzahl_cluster);
            B = labeloverlay(I,L);
            
            % Show the classified image
            imshow(B, 'parent', app.UIAxesClassmap);

            % Kuchen diagramm
            switch anzahl_cluster
                case 2
                    %labels festlegen
                    labels = {'Cluster 1','Cluster 2'};
                    %prozente berechnen
                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    X=[k_1 k_2];

                case 3
                    labels = {'Cluster 1','Cluster 2','Cluster 3'};

                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    k_3=find(L==3);
                    k_3= numel(k_3)/numel(I_g);

                    X=[k_1 k_2 k_3];

                case 4
                    labels = {'Cluster 1','Cluster 2','Cluster 3','Cluster 4'};

                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    k_3=find(L==3);
                    k_3= numel(k_3)/numel(I_g);

                    k_4=find(L==4);
                    k_4= numel(k_4)/numel(I_g);

                    X=[k_1 k_2 k_3 k_4];

                case 5
                    labels = {'Cluster 1','Cluster 2','Cluster 3','Cluster 4','Cluster 5'};

                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    k_3=find(L==3);
                    k_3= numel(k_3)/numel(I_g);

                    k_4=find(L==4);
                    k_4= numel(k_4)/numel(I_g);

                    k_5=find(L==5);
                    k_5= numel(k_5)/numel(I_g);

                    X=[k_1 k_2 k_3 k_4 k_5];

                case 6
                    labels = {'Cluster 1','Cluster 2','Cluster 3','Cluster 4','Cluster 5','Cluster 6'};

                    k_1=find(L==1);
                    k_1= numel(k_1)/numel(I_g);

                    k_2=find(L==2);
                    k_2= numel(k_2)/numel(I_g);

                    k_3=find(L==3);
                    k_3= numel(k_3)/numel(I_g);

                    k_4=find(L==4);
                    k_4= numel(k_4)/numel(I_g);

                    k_5=find(L==5);
                    k_5= numel(k_5)/numel(I_g);

                    k_6=find(L==6);
                    k_6= numel(k_6)/numel(I_g);

                    X=[k_1 k_2 k_3 k_4 k_5 k_6];
            end
            
            pie(app.UIAxesEnvironmentClassificationGraph, X);
            lgd = legend(app.UIAxesEnvironmentClassificationGraph, labels);
            lgd.Location = 'best';
        end

        % Button pushed function: ChangeMapButton
        function ChangeMapButtonPushed(app, event)
           %show the two Sliders for ChangeMap
            app.SliderStrenght.Visible='on';
            app.DetectingStrengthLabel.Visible='on';
            app.WeakLabel.Visible='on';
            app.StrongLabel.Visible='on';
            app.SliderChanges.Visible='on';
            app.ChangeSizeLabel.Visible='on';
            app.BigChangesLabel.Visible='on';
            app.SmallChangesLabel.Visible='on';
          %show the Graphs
            app.UIAxesDifferencesMap.Visible='on';
            app.UIAxesDifferencesMap_2.Visible='on';
            app.UIAxesDifferenceMapGraph.Visible='on';            
           %Image Differences 
           
           
           % !!Input: Select two images form the aligned dataset
            value_Iref = app.SliderBefore.Value;
            app.SliderBefore.Value = round(value_Iref);
            I_ref=app.files{app.SliderBefore.Value};
            
            value_Ialigned = app.SliderAfter.Value;
            app.SliderAfter.Value = round(value_Ialigned);
            I_aligned=app.files{app.SliderAfter.Value};

            % !!Input: Detecting strengths [1,10] & Change size [1,10]
            %i=6;
            value_DetectingStrength = app.SliderStrenght.Value;
            app.SliderStrenght.Value = round(value_DetectingStrength);
            i=app.SliderStrenght.Value;
            %j=1;
            value_ChangeSize = app.SliderChanges.Value;
            app.SliderChanges.Value = round(value_ChangeSize);
            j=app.SliderChanges.Value;
            
            % Calibrate 2 images, and return the change map and aligned image
            % You can set the third and fourth parameter, threshold & pixelsize
            strength = 1:1:10;
            size = 1:1:10;
            [~, changeMap] = app.images2_changemap(I_ref, I_aligned, strength(i)*0.08, round(size(j)*numel(I_aligned)*0.0001/10));
        
            %
            % Visualization 1: only binary mask
            %imshow(changeMap);
            imshow(changeMap, 'parent', app.UIAxesDifferencesMap);
            
            % Visualization 2: binary mask on camparsion image
            blend_im = imfuse(I_aligned,changeMap,'blend');
            %imshow(blend_im); 
            imshow(blend_im, 'parent', app.UIAxesDifferencesMap_2);


            %ChangeMap Change Statistics for Dataset
            ref_index = uint8(app.SliderImagePreprocessing.Value);
            
            % choose the reference image
            I_ref = app.original_dataset{ref_index};
            I_ref = google_delete(I_ref);
            p=zeros(1,app.total_pictures);
            x=1:app.total_pictures;


            % Calculate the percentage of change between reference and aligned image
            for  i = 1:app.total_pictures
               if i == ref_index
                    continue;
                end
                I = app.files{i};
                p(i) = calc_change(I_ref, I);
            end 
            
             % Build the line graph
             plot(app.UIAxesDifferenceMapGraph,x,p);
             for i=1:app.total_pictures
                 number = num2str(round(p(i)*100));
                 text(app.UIAxesDifferenceMapGraph, x(i), p(i),strcat(number,'%'));
             end
        end

        % Button pushed function: BoundingBlockButton
        function BoundingBlockButtonPushed(app, event)
            %show the ChangeSlider and disable the strengthSlider for
            %BoundingBlock
            app.SliderStrenght.Visible='off';
            app.DetectingStrengthLabel.Visible='off';
            app.WeakLabel.Visible='off';
            app.StrongLabel.Visible='off';
            
            app.SliderChanges.Visible='on';
            app.ChangeSizeLabel.Visible='on';
            app.BigChangesLabel.Visible='on';
            app.SmallChangesLabel.Visible='on';
            
          %show the Graphs
            app.UIAxesDifferencesMap.Visible='on';
            app.UIAxesDifferencesMap_2.Visible='on';
            app.UIAxesDifferenceMapGraph.Visible='on';   
          
            
            %
            % Input: Select two !aligned! images form the dataset
            
            value_Iref = app.SliderBefore.Value;
            app.SliderBefore.Value = round(value_Iref);
            I_ref=app.files{app.SliderBefore.Value};
            
            value_I= app.SliderAfter.Value;
            app.SliderAfter.Value = round(value_I);
            I=app.files{app.SliderAfter.Value};
            % Input: Change size [1,10]
            %i = 3;
            value_ChangeSize = app.SliderChanges.Value;
            app.SliderChanges.Value = round(value_ChangeSize);
            i=app.SliderChanges.Value;

            
            % Get the Information of bounding blocks
            size = 1:2:20;
            [bounding_stats,bounding_num] = bounding_change(I_ref,I,size(i));
            
            % Visualization
            %imshow(I_ref);
            imshow(I_ref, 'parent', app.UIAxesDifferencesMap);
            for index=1:bounding_num
               rectangle(app.UIAxesDifferencesMap,'Position',bounding_stats(index).BoundingBox,'curvature',[1,1],'LineWidth',1,'EdgeColor','r') ;
            end
            
            %imshow(I);
            imshow(I, 'parent', app.UIAxesDifferencesMap_2);
            for index=1:bounding_num
                rectangle(app.UIAxesDifferencesMap_2,'Position',bounding_stats(index).BoundingBox,'curvature',[1,1],'LineWidth',1,'EdgeColor','r') ;
            end
            %
            %

            %BoundingBlock Change Statistics for Dataset
            ref_index = uint8(app.SliderImagePreprocessing.Value);
            
            % choose the reference image
            I_ref = app.original_dataset{ref_index};
            I_ref = google_delete(I_ref);
            p=zeros(1,app.total_pictures);
            x=1:app.total_pictures;
            % Calculate the percentage of change between reference and aligned image
            
            for  i = 1:app.total_pictures
               if i == ref_index
                   
                    continue;
                end
                I = app.files{i};
                p(i) = calc_change(I_ref, I);
               
            end 
            
             % Build the line graph
             plot(app.UIAxesDifferenceMapGraph,x,p);
             for i=1:app.total_pictures
                 number = num2str(round(p(i)*100));
                 text(app.UIAxesDifferenceMapGraph, x(i), p(i),strcat(number,'%'));
             end
        end

        % Button pushed function: PlantsForestButton
        function PlantsForestButtonPushed(app, event)
            
            %Image1 
            Image1=app.original_dataset{app.SliderImage1.Value};
            [percentage1,I_mask1]=app.ImageEnvironmentChanges(Image1,2);
            app.PercentageOf1.Value = "Percentage of Plants/Forest";
            app.Percentage1.Value = percentage1*100;
            imshow(I_mask1, 'parent', app.UIAxesImage1);
            
            %Image2 
            Image2=app.original_dataset{app.SliderImage2.Value};
            [percentage2,I_mask2]=app.ImageEnvironmentChanges(Image2,2);
            app.PercentageOf2.Value = "Percentage of Plants/Forest";
            app.Percentage2.Value = percentage2*100;
            imshow(I_mask2, 'parent', app.UIAxesImage2);
            
            %Percentage Difference
            app.PercentageDiff.Value = abs((percentage1-percentage2)*100);
            
            %statistics 
            %
            % 
            ref_index = uint8(app.SliderImage1.Value);
            
            % choose the reference image
            I_ref = app.original_dataset{ref_index};
            I_ref = google_delete(I_ref);
            p=zeros(1,app.total_pictures);
            x=1:app.total_pictures;
            
            % Calculate the percentage of change between reference and aligned image
            
            for  i = 1:app.total_pictures
                [percentage,~]=app.ImageEnvironmentChanges(app.original_dataset{i},2);
                p(i) = percentage;
            end 
            
             % Build the line graph
             plot(app.UIAxesEnvironmentChangesGraph,x,p);
             for i=1:app.total_pictures
                 number = num2str(round(p(i)*100));
                 text(app.UIAxesEnvironmentChangesGraph, x(i), p(i),strcat(number,'%'));
             end
        end

        % Button pushed function: BuildingsSnowButton
        function BuildingsSnowButtonPushed(app, event)
                        
            %Image1 
            Image1=app.original_dataset{app.SliderImage1.Value};
            [percentage1,I_mask1]=app.ImageEnvironmentChanges(Image1,1);
            app.PercentageOf1.Value = "Percentage of Snow/Buildings";
            app.Percentage1.Value = percentage1*100;
            imshow(I_mask1, 'parent', app.UIAxesImage1);
            
            %Image2 
            Image2=app.original_dataset{app.SliderImage2.Value};
            [percentage2,I_mask2]=app.ImageEnvironmentChanges(Image2,1);
            app.PercentageOf2.Value = "Percentage of Snow/Buildings";
            app.Percentage2.Value = percentage2*100;
            imshow(I_mask2, 'parent', app.UIAxesImage2);
            
            %Percentage Difference
            app.PercentageDiff.Value = abs((percentage1-percentage2)*100);
            
            %statistics 
            %
            % 
            ref_index = uint8(app.SliderImage1.Value);
            
            % choose the reference image
            I_ref = app.original_dataset{ref_index};
            I_ref = google_delete(I_ref);
            p=zeros(1,app.total_pictures);
            x=1:app.total_pictures;

            % Calculate the percentage of change between reference and aligned image
            for  i = 1:app.total_pictures
                [percentage,~]=app.ImageEnvironmentChanges(app.original_dataset{i},1);
                p(i) = percentage;
            end 
            
             % Build the line graph
             plot(app.UIAxesEnvironmentChangesGraph,x,p);
             for i=1:app.total_pictures
                 number = num2str(round(p(i)*100));
                 text(app.UIAxesEnvironmentChangesGraph, x(i), p(i),strcat(number,'%'));
             end
        end

        % Button pushed function: WaterButton
        function WaterButtonPushed(app, event)
                        
            %Image1 
            Image1=app.original_dataset{app.SliderImage1.Value};
            [percentage1,I_mask1]=app.ImageEnvironmentChanges(Image1,3);
            app.PercentageOf1.Value = "Percentage of Water";
            app.Percentage1.Value = percentage1*100;
            imshow(I_mask1, 'parent', app.UIAxesImage1);
            
            %Image2 
            Image2=app.original_dataset{app.SliderImage2.Value};
            [percentage2,I_mask2]=app.ImageEnvironmentChanges(Image2,3);
            app.PercentageOf2.Value = "Percentage of Water";
            app.Percentage2.Value = percentage2*100;
            imshow(I_mask2, 'parent', app.UIAxesImage2);
            
            %Percentage Difference
            app.PercentageDiff.Value = abs((percentage1-percentage2)*100);
            
            %statistics 
            %
            % 
            ref_index = uint8(app.SliderImage1.Value);
            
            % choose the reference image
            I_ref = app.original_dataset{ref_index};
            I_ref = google_delete(I_ref);
            p=zeros(1,app.total_pictures);
            x=1:app.total_pictures;


            % Calculate the percentage of change between reference and aligned image
            for  i = 1:app.total_pictures
                [percentage,~]=app.ImageEnvironmentChanges(app.original_dataset{i},3);
                p(i) = percentage;
            end 
            
             % Build the line graph
             plot(app.UIAxesEnvironmentChangesGraph,x,p);
             for i=1:app.total_pictures
                 number = num2str(round(p(i)*100));
                 text(app.UIAxesEnvironmentChangesGraph, x(i), p(i),strcat(number,'%'));
             end
        end

        % Button pushed function: SandLandButton
        function SandLandButtonPushed(app, event)
                        
            %Image1 
            Image1=app.original_dataset{app.SliderImage1.Value};
            [percentage1,I_mask1]=app.ImageEnvironmentChanges(Image1,4);
            app.PercentageOf1.Value = "Percentage of Sand/Land";
            app.Percentage1.Value = percentage1*100;
            imshow(I_mask1, 'parent', app.UIAxesImage1);
            
            %Image2 
            Image2=app.original_dataset{app.SliderImage2.Value};
            [percentage2,I_mask2]=app.ImageEnvironmentChanges(Image2,4);
            app.PercentageOf2.Value = "Percentage of Sand/Land";
            app.Percentage2.Value = percentage2*100;
            imshow(I_mask2, 'parent', app.UIAxesImage2);
            
            %Percentage Difference
            app.PercentageDiff.Value = abs((percentage1-percentage2)*100);
            
            %statistics 
            %
            % 
            ref_index = uint8(app.SliderImage1.Value);
            
            % choose the reference image
            I_ref = app.original_dataset{ref_index};
            I_ref = google_delete(I_ref);
            p=zeros(1,app.total_pictures);
            x=1:app.total_pictures;


            % Calculate the percentage of change between reference and aligned image
            for  i = 1:app.total_pictures
                [percentage,~]=app.ImageEnvironmentChanges(app.original_dataset{i},4);
                p(i) = percentage;
            end 
            
             % Build the line graph
             plot(app.UIAxesEnvironmentChangesGraph,x,p);
             for i=1:app.total_pictures
                 number = num2str(round(p(i)*100));
                 text(app.UIAxesEnvironmentChangesGraph, x(i), p(i),strcat(number,'%'));
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AutomaticAnalyserofSatellitePicturesLabel = uilabel(app.UIFigure);
            app.AutomaticAnalyserofSatellitePicturesLabel.Text = 'Automatic Analyser of Satellite Pictures';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImagePreprocessingTab = uitab(app.TabGroup);
            app.ImagePreprocessingTab.Title = 'Image Preprocessing';
            app.UIAxesImagePreprocessingSmall = uiaxes(app.ImagePreprocessingTab);
            app.UIAxesImagePreprocessingSmall.XTick = [];
            app.UIAxesImagePreprocessingSmall.YTick = [];
            app.UIAxesImagePreprocessing = uiaxes(app.ImagePreprocessingTab);
            title(app.UIAxesImagePreprocessing, 'Processed Images')
            app.UIAxesImagePreprocessing.XTick = [];
            app.UIAxesImagePreprocessing.YTick = [];
            app.UploadDatasetButton = uibutton(app.ImagePreprocessingTab, 'push');
            app.UploadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDatasetButtonPushed, true);
            app.UploadDatasetButton.Text = 'Upload Dataset';
            app.SliderImagePreprocessing = uislider(app.ImagePreprocessingTab);
            app.SliderImagePreprocessing.MajorTicks = [];
            app.SliderImagePreprocessing.MajorTickLabels = {''};
            app.SliderImagePreprocessing.ValueChangedFcn = createCallbackFcn(app, @SliderImagePreprocessingValueChanged, true);
            app.SliderImagePreprocessing.MinorTicks = [];
            app.previousimageButton = uibutton(app.ImagePreprocessingTab, 'push');
            app.previousimageButton.ButtonPushedFcn = createCallbackFcn(app, @previousimageButtonPushed, true);
            app.previousimageButton.Text = 'previous image';
            app.nextimageButton = uibutton(app.ImagePreprocessingTab, 'push');
            app.nextimageButton.ButtonPushedFcn = createCallbackFcn(app, @nextimageButtonPushed, true);
            app.nextimageButton.Text = 'next image';
            app.ManualCalibrationButton = uibutton(app.ImagePreprocessingTab, 'push');
            app.ManualCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @ManualCalibrationButtonPushed, true);
            app.ManualCalibrationButton.Visible = 'off';
            app.ManualCalibrationButton.Text = 'Manual Calibration';
            app.FinishedButton = uibutton(app.ImagePreprocessingTab, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Visible = 'off';
            app.FinishedButton.Text = 'Finished';
            app.PleaseuploadadatasettostarttheimageanalysisLabel = uilabel(app.ImagePreprocessingTab);
            app.PleaseuploadadatasettostarttheimageanalysisLabel.Text = '- Please upload a dataset to start the image analysis -';
            app.StartImage = uiimage(app.ImagePreprocessingTab);
            app.StartImage.ImageSource = 'satelliten-galileo-100__v-gseagaleriexl.jpg';
            app.ChooseReferenceImageButton = uibutton(app.ImagePreprocessingTab, 'push');
            app.ChooseReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseReferenceImageButtonPushed, true);
            app.ChooseReferenceImageButton.Visible = 'off';
            app.ChooseReferenceImageButton.Text = 'Choose Reference Image';
            app.DifferenceMapTab = uitab(app.TabGroup);
            app.DifferenceMapTab.Title = 'Difference Map';
            app.UIAxesDifferencesMap = uiaxes(app.DifferenceMapTab);
            app.UIAxesDifferenceMapBefore = uiaxes(app.DifferenceMapTab);
            app.UIAxesDifferenceMapBefore.XTick = [];
            app.UIAxesDifferenceMapBefore.YTick = [];
            app.UIAxesDifferenceMapAfter = uiaxes(app.DifferenceMapTab);
            app.UIAxesDifferenceMapAfter.XTick = [];
            app.UIAxesDifferenceMapAfter.YTick = [];
            app.UIAxesDifferenceMapGraph = uiaxes(app.DifferenceMapTab);
            app.UIAxesDifferencesMap_2 = uiaxes(app.DifferenceMapTab);
            app.SliderBefore = uislider(app.DifferenceMapTab);
            app.SliderBefore.ValueChangedFcn = createCallbackFcn(app, @SliderBeforeValueChanged, true);
            app.SliderAfter = uislider(app.DifferenceMapTab);
            app.SliderAfter.ValueChangedFcn = createCallbackFcn(app, @SliderAfterValueChanged, true);
            app.SliderChanges = uislider(app.DifferenceMapTab);
            app.SliderChanges.Limits = [1 10];
            app.SliderChanges.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SliderChanges.MinorTicks = [];
            app.SliderChanges.Value = 1;
            app.SmallChangesLabel = uilabel(app.DifferenceMapTab);
            app.SmallChangesLabel.Text = {'Small'; 'Changes'};
            app.BigChangesLabel = uilabel(app.DifferenceMapTab);
            app.BigChangesLabel.Text = {'Big'; 'Changes'};
            app.Image1Label = uilabel(app.DifferenceMapTab);
            app.Image1Label.Text = 'Image 1';
            app.Image2Label = uilabel(app.DifferenceMapTab);
            app.Image2Label.Text = 'Image 2 ';
            app.ChangeStatisticsforDatasetLabel = uilabel(app.DifferenceMapTab);
            app.ChangeStatisticsforDatasetLabel.Text = 'Change Statistics for Dataset';
            app.SliderStrenght = uislider(app.DifferenceMapTab);
            app.SliderStrenght.Limits = [1 10];
            app.SliderStrenght.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SliderStrenght.MinorTicks = [];
            app.SliderStrenght.Value = 1;
            app.WeakLabel = uilabel(app.DifferenceMapTab);
            app.WeakLabel.Text = 'Weak';
            app.StrongLabel = uilabel(app.DifferenceMapTab);
            app.StrongLabel.Text = 'Strong';
            app.DetectingStrengthLabel = uilabel(app.DifferenceMapTab);
            app.DetectingStrengthLabel.Text = 'Detecting Strength';
            app.ChangeSizeLabel = uilabel(app.DifferenceMapTab);
            app.ChangeSizeLabel.Text = 'Change Size';
            app.ChangeMapButton = uibutton(app.DifferenceMapTab, 'push');
            app.ChangeMapButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeMapButtonPushed, true);
            app.ChangeMapButton.Text = 'ChangeMap';
            app.BoundingBlockButton = uibutton(app.DifferenceMapTab, 'push');
            app.BoundingBlockButton.ButtonPushedFcn = createCallbackFcn(app, @BoundingBlockButtonPushed, true);
            app.BoundingBlockButton.Text = 'BoundingBlock';
            app.ShowDiffenceswithLabel = uilabel(app.DifferenceMapTab);
            app.ShowDiffenceswithLabel.Text = 'Show Diffences with :';
            app.TimelapseTab = uitab(app.TabGroup);
            app.TimelapseTab.Title = 'Timelapse';
            app.UIAxesStartImageTimelapse = uiaxes(app.TimelapseTab);
            app.UIAxesStartImageTimelapse.XTick = [];
            app.UIAxesStartImageTimelapse.YTick = [];
            app.SliderTimelapse = uislider(app.TimelapseTab);
            app.SliderTimelapse.MajorTicks = [];
            app.SliderTimelapse.ValueChangedFcn = createCallbackFcn(app, @SliderTimelapseValueChanged, true);
            app.SliderTimelapse.MinorTicks = [];
            app.EnvironmentChangesTab = uitab(app.TabGroup);
            app.EnvironmentChangesTab.Title = 'Environment Changes';
            app.UIAxesEnvironmentChangesGraph = uiaxes(app.EnvironmentChangesTab);
            app.UIAxesImage1 = uiaxes(app.EnvironmentChangesTab);
            title(app.UIAxesImage1, 'Image 1')
            app.UIAxesImage1.XTick = [];
            app.UIAxesImage1.YTick = [];
            app.UIAxesImage2 = uiaxes(app.EnvironmentChangesTab);
            title(app.UIAxesImage2, 'Image 2')
            app.UIAxesImage2.XTick = [];
            app.UIAxesImage2.YTick = [];
            app.ChangeStatisticsforDatasetLabel2 = uilabel(app.EnvironmentChangesTab);
            app.ChangeStatisticsforDatasetLabel2.Text = 'Change Statistics for Dataset';
            app.SliderImage1 = uislider(app.EnvironmentChangesTab);
            app.SliderImage1.ValueChangedFcn = createCallbackFcn(app, @SliderImage1ValueChanged, true);
            app.SliderImage2 = uislider(app.EnvironmentChangesTab);
            app.SliderImage2.ValueChangedFcn = createCallbackFcn(app, @SliderImage2ValueChanged, true);
            app.SliderImage2.Value = 2;
            app.PlantsForestButton = uibutton(app.EnvironmentChangesTab, 'push');
            app.PlantsForestButton.ButtonPushedFcn = createCallbackFcn(app, @PlantsForestButtonPushed, true);
            app.PlantsForestButton.Text = 'Plants/Forest';
            app.WaterButton = uibutton(app.EnvironmentChangesTab, 'push');
            app.WaterButton.ButtonPushedFcn = createCallbackFcn(app, @WaterButtonPushed, true);
            app.WaterButton.Text = 'Water';
            app.SandLandButton = uibutton(app.EnvironmentChangesTab, 'push');
            app.SandLandButton.ButtonPushedFcn = createCallbackFcn(app, @SandLandButtonPushed, true);
            app.SandLandButton.Text = 'Sand/Land';
            app.BuildingsSnowButton = uibutton(app.EnvironmentChangesTab, 'push');
            app.BuildingsSnowButton.ButtonPushedFcn = createCallbackFcn(app, @BuildingsSnowButtonPushed, true);
            app.BuildingsSnowButton.Text = 'Buildings/ Snow';
            app.ObjectTypeLabel = uilabel(app.EnvironmentChangesTab);
            app.ObjectTypeLabel.Text = 'Object Type:';
            app.Percentage1 = uieditfield(app.EnvironmentChangesTab, 'numeric');
            app.PercentageOf1 = uieditfield(app.EnvironmentChangesTab, 'text');
            app.Percentage2 = uieditfield(app.EnvironmentChangesTab, 'numeric');
            app.PercentageOf2 = uieditfield(app.EnvironmentChangesTab, 'text');
            app.PercentageDiff = uieditfield(app.EnvironmentChangesTab, 'numeric');
            app.PercentageOfDiff = uieditfield(app.EnvironmentChangesTab, 'text');
            app.PercentageOfDiff.Value = 'Percentage Difference';
            app.EnvironmentClassificationTab = uitab(app.TabGroup);
            app.EnvironmentClassificationTab.Title = 'Environment Classification';
            app.UIAxesOriginal = uiaxes(app.EnvironmentClassificationTab);
            title(app.UIAxesOriginal, 'Original Image')
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesClassmap = uiaxes(app.EnvironmentClassificationTab);
            title(app.UIAxesClassmap, 'Original Image + Classmap')
            app.UIAxesClassmap.XTick = [];
            app.UIAxesClassmap.YTick = [];
            app.UIAxesEnvironmentClassificationGraph = uiaxes(app.EnvironmentClassificationTab);
            app.ClassStatisticsLabel = uilabel(app.EnvironmentClassificationTab);
            app.ClassStatisticsLabel.Text = 'Class Statistics';
            app.NumberofClustersSliderLabel = uilabel(app.EnvironmentClassificationTab);
            app.NumberofClustersSliderLabel.Text = 'Number of Clusters';
            app.NumberofClustersSlider = uislider(app.EnvironmentClassificationTab);
            app.NumberofClustersSlider.Limits = [2 6];
            app.NumberofClustersSlider.MajorTicks = [2 3 4 5 6];
            app.NumberofClustersSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofClustersSliderValueChanged, true);
            app.NumberofClustersSlider.MinorTicks = [];
            app.NumberofClustersSlider.Value = 2;
            app.SliderEnvironmentClassification = uislider(app.EnvironmentClassificationTab);
            app.SliderEnvironmentClassification.ValueChangedFcn = createCallbackFcn(app, @SliderEnvironmentClassificationValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CV2021Challenge_TabManager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end